classdef app_beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup_menu                  matlab.ui.container.TabGroup
        histogram                      matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        histogram_gray                 matlab.ui.container.Tab
        UIAxes_grayimage               matlab.ui.control.UIAxes
        UIAxes_grayimage_histogram     matlab.ui.control.UIAxes
        histogram_equalization         matlab.ui.container.Tab
        UIAxes_equalimage              matlab.ui.control.UIAxes
        UIAxes_equalimage_histogram    matlab.ui.control.UIAxes
        histogram_match                matlab.ui.container.Tab
        Label_filepath_match           matlab.ui.control.Label
        Button_matchimage              matlab.ui.control.Button
        UIAxes_matchimage              matlab.ui.control.UIAxes
        UIAxes_matchimage_histogram    matlab.ui.control.UIAxes
        UIAxes_matchedimage            matlab.ui.control.UIAxes
        UIAxes_matchedimage_histogram  matlab.ui.control.UIAxes
        enhance                        matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        transformation_linear          matlab.ui.container.Tab
        Label_formula1                 matlab.ui.control.Label
        Button_1                       matlab.ui.control.Button
        input_1                        matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        UIAxes_tanimage                matlab.ui.control.UIAxes
        transformation_nonlinear       matlab.ui.container.Tab
        Label_formula2                 matlab.ui.control.Label
        Label_formula3                 matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        input_2                        matlab.ui.control.NumericEditField
        input_3                        matlab.ui.control.NumericEditField
        input_4                        matlab.ui.control.NumericEditField
        input_5                        matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        EditField_4Label               matlab.ui.control.Label
        EditField_3Label               matlab.ui.control.Label
        EditField_2Label               matlab.ui.control.Label
        UIAxes_logimage                matlab.ui.control.UIAxes
        UIAxes_expimage                matlab.ui.control.UIAxes
        Label_filepath                 matlab.ui.control.Label
        button_readimage               matlab.ui.control.Button
        UIAxes_image                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
    end
    
    methods (Access = public)
        function histo = gethistogram(app, image)
            histo = zeros(256,1);
            [h,w] = size(image);
            for x = 1:w
                for y = 1:h
                    histo(image(y,x)+1) = histo(image(y,x)+1) + 1;
                end
            end
        end

        function newimage = equalization(app, image)
            histo = imhist(image);
            [h,w] = size(image);
            newimage = zeros(h,w);
            s = zeros(256,1);
            s(1) = histo(1);
            for t = 2:256
                s(t) = s(t-1) + histo(t);
            end
            for x = 1:h
                for y = 1:w
                    newimage(x,y) = s(image(x,y)+1) / (h*w);
                end
            end
            newimage = im2uint8(newimage);
        end

        function newimage = match(app, image1, image2)
            histo = imhist(image2);
            newimage = histeq(image1,histo);
        end

        function newimage = linear(app, image, x)
            x = pi * x / 180;
            image = im2double(image);
            newimage = image * tan(x);
            newimage = min(255,newimage);
            newimage = max(0,newimage);
            newimage = im2uint8(newimage);
        end

        function newimage = nonlinearlog(app, image, c)
            image = double(image);
            newimage = (log(image + 1)) * c;
            newimage = min(255,newimage);
            newimage = max(0,newimage);
            newimage = uint8(newimage);
        end

        function newimage = nonlinearexp(app, image, a, b, c)
            image = double(image);
            newimage = power(b,(c * (image - a))) - 1;
            newimage = min(255,newimage);
            newimage = max(0,newimage);
            newimage = uint8(newimage);
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: button_readimage
        function button_readimageButtonPushed(app, event)
            [f,p]=uigetfile({'*.jpg;*.tif;*.png;*.gif;*.bmp','All Image Files'});  
            if (ischar(p))
               fname = strcat(p,f);
               app.Label_filepath.Text = fname;

               image = imread(fname);
               imshow(image,'parent',app.UIAxes_image);

               image = rgb2gray(image);
               imshow(image,'Parent',app.UIAxes_grayimage);

               data = gethistogram(app, image);
               bar(app.UIAxes_grayimage_histogram,data);

               newimage = equalization(app, image);
               imshow(newimage,'Parent',app.UIAxes_equalimage);

               newdata = gethistogram(app, newimage);
               bar(app.UIAxes_equalimage_histogram,newdata);
            end
        end

        % Button pushed function: Button_matchimage
        function Button_matchimagePushed(app, event)
            [f,p]=uigetfile({'*.jpg;*.tif;*.png;*.gif;*.bmp','All Image Files'});  
            if (ischar(p))
               fname = strcat(p,f);
               app.Label_filepath_match.Text = fname;

               image = imread(fname);
               image = rgb2gray(image);
               imshow(image,'Parent',app.UIAxes_matchimage);

               data = gethistogram(app, image);
               bar(app.UIAxes_matchimage_histogram,data);

               ini_image = imread(app.Label_filepath.Text);
               ini_image = rgb2gray(ini_image);
               newimage = match(app,ini_image,image);
               imshow(newimage,'Parent',app.UIAxes_matchedimage);
               newdata = gethistogram(app, newimage);
               bar(app.UIAxes_matchedimage_histogram,newdata);
               
            end
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            image = imread(app.Label_filepath.Text);
            image = rgb2gray(image);
            newimage = linear(app, image, app.input_1.Value);
            imshow(newimage,'Parent',app.UIAxes_tanimage);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            image = imread(app.Label_filepath.Text);
            image = rgb2gray(image);
            newimage = nonlinearlog(app, image, app.input_2.Value);
            imshow(newimage,'Parent',app.UIAxes_logimage);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            image = imread(app.Label_filepath.Text);
            image = rgb2gray(image);
            newimage = nonlinearexp(app, image, app.input_3.Value, app.input_4.Value, app.input_5.Value);
            imshow(newimage,'Parent',app.UIAxes_expimage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_image = uiaxes(app.UIFigure);
            title(app.UIAxes_image, '原图')
            xlabel(app.UIAxes_image, 'X')
            ylabel(app.UIAxes_image, 'Y')
            zlabel(app.UIAxes_image, 'Z')
            app.button_readimage = uibutton(app.UIFigure, 'push');
            app.button_readimage.ButtonPushedFcn = createCallbackFcn(app, @button_readimageButtonPushed, true);
            app.button_readimage.Text = '导入图片';
            app.Label_filepath = uilabel(app.UIFigure);
            app.Label_filepath.Text = 'filepath name';
            app.TabGroup_menu = uitabgroup(app.UIFigure);
            app.histogram = uitab(app.TabGroup_menu);
            app.histogram.Title = '直方图';
            app.TabGroup = uitabgroup(app.histogram);
            app.histogram_gray = uitab(app.TabGroup);
            app.histogram_gray.Title = '灰度直方图';
            app.UIAxes_grayimage_histogram = uiaxes(app.histogram_gray);
            title(app.UIAxes_grayimage_histogram, '直方图')
            xlabel(app.UIAxes_grayimage_histogram, 'X')
            ylabel(app.UIAxes_grayimage_histogram, 'Y')
            zlabel(app.UIAxes_grayimage_histogram, 'Z')
            app.UIAxes_grayimage = uiaxes(app.histogram_gray);
            title(app.UIAxes_grayimage, '灰度化图像')
            xlabel(app.UIAxes_grayimage, 'X')
            ylabel(app.UIAxes_grayimage, 'Y')
            zlabel(app.UIAxes_grayimage, 'Z')
            app.histogram_equalization = uitab(app.TabGroup);
            app.histogram_equalization.Title = '直方图均衡化';
            app.UIAxes_equalimage_histogram = uiaxes(app.histogram_equalization);
            title(app.UIAxes_equalimage_histogram, '直方图')
            xlabel(app.UIAxes_equalimage_histogram, 'X')
            ylabel(app.UIAxes_equalimage_histogram, 'Y')
            zlabel(app.UIAxes_equalimage_histogram, 'Z')
            app.UIAxes_equalimage = uiaxes(app.histogram_equalization);
            title(app.UIAxes_equalimage, '均衡化图像')
            xlabel(app.UIAxes_equalimage, 'X')
            ylabel(app.UIAxes_equalimage, 'Y')
            zlabel(app.UIAxes_equalimage, 'Z')
            app.histogram_match = uitab(app.TabGroup);
            app.histogram_match.Title = '直方图匹配';
            app.UIAxes_matchedimage_histogram = uiaxes(app.histogram_match);
            title(app.UIAxes_matchedimage_histogram, '直方图')
            xlabel(app.UIAxes_matchedimage_histogram, 'X')
            ylabel(app.UIAxes_matchedimage_histogram, 'Y')
            zlabel(app.UIAxes_matchedimage_histogram, 'Z')
            app.UIAxes_matchedimage = uiaxes(app.histogram_match);
            title(app.UIAxes_matchedimage, '匹配化图像')
            xlabel(app.UIAxes_matchedimage, 'X')
            ylabel(app.UIAxes_matchedimage, 'Y')
            zlabel(app.UIAxes_matchedimage, 'Z')
            app.UIAxes_matchimage_histogram = uiaxes(app.histogram_match);
            title(app.UIAxes_matchimage_histogram, '直方图')
            xlabel(app.UIAxes_matchimage_histogram, 'X')
            ylabel(app.UIAxes_matchimage_histogram, 'Y')
            zlabel(app.UIAxes_matchimage_histogram, 'Z')
            app.UIAxes_matchimage = uiaxes(app.histogram_match);
            title(app.UIAxes_matchimage, '匹配图像')
            xlabel(app.UIAxes_matchimage, 'X')
            ylabel(app.UIAxes_matchimage, 'Y')
            zlabel(app.UIAxes_matchimage, 'Z')
            app.Button_matchimage = uibutton(app.histogram_match, 'push');
            app.Button_matchimage.ButtonPushedFcn = createCallbackFcn(app, @Button_matchimagePushed, true);
            app.Button_matchimage.Text = '导入匹配图像';
            app.Label_filepath_match = uilabel(app.histogram_match);
            app.Label_filepath_match.Text = 'filepath name';
            app.enhance = uitab(app.TabGroup_menu);
            app.enhance.Title = '灰度级变换';
            app.TabGroup2 = uitabgroup(app.enhance);
            app.transformation_linear = uitab(app.TabGroup2);
            app.transformation_linear.Title = '线性变换';
            app.UIAxes_tanimage = uiaxes(app.transformation_linear);
            title(app.UIAxes_tanimage, '基础线性变换')
            xlabel(app.UIAxes_tanimage, 'X')
            ylabel(app.UIAxes_tanimage, 'Y')
            zlabel(app.UIAxes_tanimage, 'Z')
            app.Label_2 = uilabel(app.transformation_linear);
            app.Label_2.Text = 'α';
            app.input_1 = uieditfield(app.transformation_linear, 'numeric');
            app.Button_1 = uibutton(app.transformation_linear, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '增强';
            app.Label_formula1 = uilabel(app.transformation_linear);
            app.Label_formula1.Text = 'g(x,y)　=　f(x,y)　*　tanα';
            app.transformation_nonlinear = uitab(app.TabGroup2);
            app.transformation_nonlinear.Title = '非线性变换';
            app.UIAxes_expimage = uiaxes(app.transformation_nonlinear);
            title(app.UIAxes_expimage, '指数变换')
            xlabel(app.UIAxes_expimage, 'X')
            ylabel(app.UIAxes_expimage, 'Y')
            zlabel(app.UIAxes_expimage, 'Z')
            app.UIAxes_logimage = uiaxes(app.transformation_nonlinear);
            title(app.UIAxes_logimage, '对数变换')
            xlabel(app.UIAxes_logimage, 'X')
            ylabel(app.UIAxes_logimage, 'Y')
            zlabel(app.UIAxes_logimage, 'Z')
            app.EditField_2Label = uilabel(app.transformation_nonlinear);
            app.EditField_2Label.Text = 'ｃ';
            app.EditField_3Label = uilabel(app.transformation_nonlinear);
            app.EditField_3Label.Text = 'ａ';
            app.EditField_4Label = uilabel(app.transformation_nonlinear);
            app.EditField_4Label.Text = 'ｂ';
            app.EditField_5Label = uilabel(app.transformation_nonlinear);
            app.EditField_5Label.Text = 'ｃ';
            app.input_5 = uieditfield(app.transformation_nonlinear, 'numeric');
            app.input_4 = uieditfield(app.transformation_nonlinear, 'numeric');
            app.input_4.Value = 1;
            app.input_3 = uieditfield(app.transformation_nonlinear, 'numeric');
            app.input_2 = uieditfield(app.transformation_nonlinear, 'numeric');
            app.Button_3 = uibutton(app.transformation_nonlinear, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '增强';
            app.Button_2 = uibutton(app.transformation_nonlinear, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '增强';
            app.Label_formula3 = uilabel(app.transformation_nonlinear);
            app.Label_formula3.Text = 'g(x,y) = b^c*[f(x,y)-a] - 1';
            app.Label_formula2 = uilabel(app.transformation_nonlinear);
            app.Label_formula2.Text = 'g(x,y) ＝ c * log[f(x,y)+1]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_beta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end